classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        HTML2              matlab.ui.control.HTML
        Button_1           matlab.ui.control.Button
        Button_2           matlab.ui.control.Button
        Button_4           matlab.ui.control.Button
        HTML3              matlab.ui.control.HTML
        Label              matlab.ui.control.Label
        Label_2            matlab.ui.control.Label
        Label_3            matlab.ui.control.Label
        Label_4            matlab.ui.control.Label
        Label_5            matlab.ui.control.Label
        Label_6            matlab.ui.control.Label
        Label_7            matlab.ui.control.Label
        TextArea           matlab.ui.control.TextArea
        GaugeLabel         matlab.ui.control.Label
        Gauge              matlab.ui.control.Gauge
        Gauge_2            matlab.ui.control.Gauge
        Gauge_2Label       matlab.ui.control.Label
        HTML_clock         matlab.ui.control.HTML
        Image_weatherIcon  matlab.ui.control.Image
        UIAxes             matlab.ui.control.UIAxes
        HTML_Title         matlab.ui.control.HTML
        Button_5           matlab.ui.control.Button
        DropDown           matlab.ui.control.DropDown
        HTML_BorderSmall   matlab.ui.control.HTML
        DropDown_Year      matlab.ui.control.DropDown
        DropDown_Month     matlab.ui.control.DropDown
        Button_3           matlab.ui.control.Button
    end

    
    properties (Access = private)
        City = '西安' % Description
        CityCode = '101110101'
        City_Pinyin = 'xian'
        City_Items = {'西安', '上海'};
        City_Codes = {'101110101', '101020100'};
        M = containers.Map({'101110101', '101020100'}, {'西安', '上海'});
        M_pinyin = containers.Map({'101110101', '101020100'}, {'xian', 'shanghai'});
        Years = {2021, 2020, 2019};
        Months = 1:12;
        key = '';
    end
    
    methods (Access = private)
        
        function updateCity(app)
            app.CityCode = app.DropDown.Value;
            app.City = app.M(app.CityCode);
            app.City_Pinyin = app.M_pinyin(app.CityCode);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Position = [300 200 907 570];
            hide = [app.Gauge, app.GaugeLabel, app.Gauge_2, app.Gauge_2Label, app.TextArea, app.Image_weatherIcon];
            for h=hide
                h.Visible = 'off';
            end
            % 格式统一设置
            labels_chinese = [app.Label, app.Label_2, app.Label_4, app.Label_6, app.GaugeLabel, app.Gauge_2Label];
            for label=labels_chinese
                label.FontColor = [1,1,1]; %[236, 236, 234]/255;
                label.FontName = '华文中宋';
                label.FontSize = 20;
                label.Text = '';
            end
            app.GaugeLabel.FontSize = 16;
            app.Gauge_2Label.FontSize = 16;
            app.GaugeLabel.FontColor = [94, 167, 242]/255;
            app.Gauge_2Label.FontColor = [94, 167, 242]/255;
            app.Gauge.MajorTicks = [0, 10, 20, 30, 40];
            app.Gauge.Limits = [0, 40];
            
            labels_english = [app.Label_3, app.Label_5, app.Label_7];
            for label=labels_english
                label.FontColor = [236, 236, 234]/255;
                label.FontName = '华文中宋';
                label.FontSize = 20;
                label.Text = '';
            end
            % 关闭坐标轴，因为不能关闭底色，所以就把它大小设为0，假装关闭
            app.UIAxes.Visible = 'off';
            app.UIAxes.Position = [29 115 0 0];
%             app.Knob.ItemsData = 1:10;
            app.HTML_Title.Visible = 'on';
            % 城市代码列表 https://github.com/qwd/LocationList/blob/master/China-City-List-latest.csv
            app.DropDown.Items = app.City_Items;
            app.DropDown.ItemsData = app.City_Codes;
            app.DropDown_Year.Items = string(app.Years);
            app.DropDown_Year.ItemsData = app.Years;
            app.DropDown_Month.Items = string(app.Months);
            app.DropDown_Month.ItemsData = app.Months;
            app.DropDown_Month.Value = 9;
            app.DropDown_Year.Value = 2021;
            
            app.UIFigure.Name = 'MF 天气预报软件 v0.0.1';
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            
            updateCity(app);
            app.HTML_Title.Visible = 'off';
            % 关闭坐标轴
            clf; cla; close();
            app.UIAxes.Visible = 'off';
            app.UIAxes.Position = [29 115 0 0];
            q = sprintf('https://devapi.qweather.com/v7/weather/7d?location=%s&key=%s', app.CityCode, app.key);
            data = webread(q);
            q2 = sprintf('https://devapi.qweather.com/v7/air/now?location=%s&key=%s', app.CityCode, app.key);
            air_quality = webread(q2);
            i = 1;   % 1为当天
            s_textDay = sprintf('%s今日天气状况：\t   %s', app.City, data.daily(i).textDay);
            
            icon_path = sprintf('icons/%s-fill.svg', data.daily(i).iconDay);
            app.Image_weatherIcon.ImageSource = icon_path;
            app.Image_weatherIcon.BackgroundColor = [0.8, 0.8, 0.8];
            
            s_temp = sprintf('%s℃ ~ %s℃', data.daily(i).tempMin, data.daily(i).tempMax);
            s_windDir = sprintf('%s', data.daily(i).windDirDay);
%             s_windScale = sprintf('%s级, %dkm/h',  data.daily(i).windScaleDay,  data.daily(i).windSpeedDay);
            s_humidity = sprintf('%s%%', data.daily(i).humidity);
            s_aircondition = sprintf("%s, AQI %s", air_quality.now.category, air_quality.now.aqi);

            to_be_shown = [app.Gauge, app.GaugeLabel, app.Gauge_2, app.Gauge_2Label, app.TextArea, app.Image_weatherIcon];
%                            app.Label, app.Label_2, app.Label_3, app.Label_4, app.Label_5];
            for h=to_be_shown
                h.Visible = 'on';
            end
            app.Gauge.Value = (str2double(data.daily(i).tempMin) + str2double(data.daily(i).tempMax))/2;
            app.GaugeLabel.Text = '平均气温';
            app.Gauge_2.Value = str2double(replace(s_humidity, '%', ''));
            app.Gauge_2Label.Text = '湿度(%)';
            
            % 最下方文字提示
            app.TextArea.FontName = '仿宋';
            app.TextArea.FontSize = 20;
            app.TextArea.FontColor = [34, 239, 243]/255;
            app.TextArea.FontColor = [1,1,1];
            app.TextArea.BackgroundColor = [50, 50, 50]/255;
%             app.TextArea.BackgroundColor = [0.3010 0.7450 0.9330];
            
            app.Label.Text = s_textDay;
            app.Label_2.Text = '温度：';
            app.Label_3.Text = s_temp;
            app.Label_4.Text = '风向：';
            app.Label_5.Text = s_windDir;
            app.Label_6.Text = '空气质量：';
            app.Label_7.Text = s_aircondition;
            q3 = sprintf('https://devapi.qweather.com/v7/indices/1d?location=%s&type=1,3&key=%s', app.CityCode, app.key);
            life_index = webread(q3);
            app.TextArea.Value = sprintf('·%s\n·%s', life_index.daily(1).text, life_index.daily(2).text);
     
            
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            updateCity(app);
            year = app.DropDown_Year.Value;
            
            % 历史天气功能
%             selection = uiconfirm(app.UIFigure,'此功能暂未开放，你是故意找茬是吧','【提示】',...
%                                     'Icon','warning');
%             value = app.Knob.Value;
%             app.LabelTemp.Text = sprintf("%d", value);
%             if value >= 1 && value <= 10
%                 month = floor(value);
%             else
%                 uiconfirm(app.UIFigure,'月份输入错误','【找茬】',...
%                                     'Icon','error');
%             end
            month = app.DropDown_Month.Value;
            if (year == 2021 && month > 10)
                uiconfirm(app.UIFigure,'月份输入错误','【找茬】',...
                                    'Icon','error');
            end
            zero = '';
            if month <= 9
                zero = '0';
            end
            filename = sprintf('./weather_%d%s%d.html', year, zero, month);
            if ~exist(filename, 'file')
                query_url = sprintf('http://lishi.tianqi.com/%s/%d%s%d.html', app.City_Pinyin, year, zero, month);
%                 app.LabelDebug.Text = query_url;
                websave(filename, query_url);
            end
%             uiconfirm(app.UIFigure, query_url, 'ff');
            html = fileread(filename);
            
            tree = htmlTree(html);
            selector = '.thrui';
            % 参考https://www.w3schools.com/cssref/css_selectors.asp
            subtree = findElement(tree, selector);
            date = findElement(subtree, '.th200');
            tempMax = findElement(subtree, 'div:nth-child(2)');
            tempMin = findElement(subtree, 'div:nth-child(3)');
            textDay = findElement(subtree, 'div:nth-child(4)');
            windDir = findElement(subtree, 'div:nth-child(5)');
            
            x = regexprep(extractHTMLText(date), '星期.| ', '');
            % x = regexprep(x, '\d{4}-', '');
            x = datenum(x);
            tempMax = str2double(extractHTMLText(tempMax).replace('℃', ''));
            tempMin = str2double(extractHTMLText(tempMin).replace('℃', ''));
            tempMean = (mean(tempMax) + mean(tempMin))/2;
            textDay = extractHTMLText(textDay);
            f = figure('Position', [330 230 900 570], 'NumberTitle', 'off');
            f.Name = sprintf('%s %d年%d月天气', app.City, year, month);
            
            ax = subplot(2,2,[1 2]);
            
            plot(ax, x, tempMax, 'ok-', 'linewidth', 1.2, 'markerfacecolor', [200, 33, 33]/255, 'MarkerSize', 6);
            hold('on');
            plot(ax, x, tempMin, 'ok-', 'linewidth', 1.2, 'markerfacecolor', [36, 33, 225]/255, 'MarkerSize', 6);
%             datetick(ax, 'x', 29);
            plot(ax, [x(1)-10 x(end)+10], [mean(tempMax) mean(tempMax)], 'Color', 'r', 'LineStyle', '--');
            text(ax, (x(1)+x(end))/2, mean(tempMax)+1, '均值');
            plot(ax, [x(1)-10 x(end)+10], [mean(tempMin) mean(tempMin)], 'Color', 'b', 'LineStyle', '--');
            text(ax, (x(1)+x(end))/2, mean(tempMin)+1, '均值');
        
            xticklabels(ax, 1:length(x));
            xlim([x(1)-1 x(end)+1]);
            set(ax, 'xtick', x);
            set(ax, 'xgrid', 'on');
            title(ax, sprintf('%d年%d月气温图', year, month));
            xlabel(ax, '日期');
            ylabel(ax, '气温(℃)');
            
            ax2 = subplot(2,2,3);
            a = tabulate(textDay); % b为需要绘制饼图的原始数据列，生成新的一个矩阵a
            b = cell2mat(a(:,3));
            labels = a(:,1);
            pie(ax2, b, labels);
            title(ax2, '天气状况分布');
            
            ax3 = subplot(2,2,4, polaraxes);
            windDir = regexprep(extractHTMLText(windDir),'风 .级', '');
            key = {'北', '东北', '东', '东南', '南', '西南', '西', '西北'};
            value = 0:45:315;
            m = containers.Map(key, value);
            wind_values = zeros(1, length(windDir));
            for i=1:length(windDir)
                wind_values(i) = m(windDir(i));
            end
            N = 2;
%             mid = length(windDir)/N;
            p = polarhistogram(ax3, wind_values, 8, 'BinMethod','sqrt');
            p.FaceColor = [0.1 0.5 0.9];
            p.EdgeColor = 'blue';
            p.FaceAlpha = 0.3;
            legend(ax3, '风向');
            
            
%             hold(ax3, 'on');
%             p = polarhistogram(ax3, wind_values(mid+1:end), 8);
%             p.FaceColor = [0.9 0.5 0.1];
%             p.EdgeColor = 'red';
%             p.FaceAlpha = 0.3;

            title(ax3, '风向玫瑰图');
            

        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            updateCity(app);
            N = 7;
            app.HTML_Title.Visible = 'off';
            tempMax = [16    18    14    15    18    16    15];
            tempMin = [ 9     7     8    12    12    10    11];
            humidity = [34 20 40 29 76 60 20];
            textDay = string(N);
            % 未来7天天气
            q = sprintf('https://devapi.qweather.com/v7/weather/7d?location=%s&key=%s', app.CityCode, app.key);
            data = webread(q);
            for i=1:N
%                 data.daily(i).fxDate
                tempMax(i) = str2double(data.daily(i).tempMax);
                tempMin(i) = str2double(data.daily(i).tempMin);
            %     data.daily(i).windDirDay
%                 data.daily(i).windScaleDay
            %     data.daily(i).windDirNight
            %     data.daily(i).windScaleNight
%                 data.daily(i).precip
                humidity(i) = str2double(replace(data.daily(i).humidity, '%', ''));
                textDay(i) = data.daily(i).textDay;
            end
%             
%             clear all;
%             now = datetime('now') + caldays(0:6);
%             x = day(now);
%             plot(x, data.daily.tempMax)
            app.UIAxes.Visible = 'on';
            dx = 4;
            app.UIAxes.Position = [29+2 115+2 695-7 424-4];
            
            future_7_days = datetime('now') + caldays(0:6);
            x = datenum(future_7_days);
            
            plot(app.UIAxes, x, tempMax, ...
                    'ok-', 'linewidth', 1.8, 'markerfacecolor', [200, 33, 33]/255, 'MarkerSize', 8);
            hold(app.UIAxes, 'on')
            plot(app.UIAxes, x, tempMin, ...
                    'ok-', 'linewidth', 1.8, 'markerfacecolor', [36, 33, 225]/255, 'MarkerSize', 8);
            datetick(app.UIAxes, 'x', 29); % 关键：若不设置坐标轴会为700000多的数字，这样是让它显示第29种的日期
            set(app.UIAxes, 'xtick', x);
            set(app.UIAxes, 'xgrid', 'on');
            title(app.UIAxes, '七日天气预报');
            xlabel(app.UIAxes, '日期');
            ylabel(app.UIAxes, '气温(℃)');
            ylim(app.UIAxes,[min(tempMin)-20 max(tempMax)+3])
            
            for i=1:7
                text(app.UIAxes, x(i), tempMax(i)+1, sprintf('%d℃', tempMax(i)), ...
                    'FontSize', 15, 'FontName', '华文中宋');
                text(app.UIAxes, x(i), tempMin(i)+1, sprintf('%d℃', tempMin(i)), ...
                    'FontSize', 15, 'FontName', '华文中宋');
            end
            
            yyaxis(app.UIAxes, 'right');
            set(app.UIAxes, 'ytick', [])
            set(app.UIAxes, 'yticklabel', [])
            
            plot(app.UIAxes, x, humidity, ...
                    'ok-', 'linewidth', 1.8, 'markerfacecolor', [33, 200, 33]/255);
%             ylabel(app.UIAxes, '湿度(%)', 'Color', [0.15 0.15 0.15]);
            ylim(app.UIAxes,[10 180])
            
             for i=1:7
                text(app.UIAxes, x(i), humidity(i)+8, sprintf('%d%%', humidity(i)), ...
                    'FontSize', 14, 'FontName', '华文中宋');
                text(app.UIAxes, x(i), humidity(i)-7, sprintf('%s', textDay(i)), ...
                    'FontSize', 15, 'FontName', '华文中宋');
            end
            
            legend(app.UIAxes, '最高气温', '最低气温', '湿度', 'Location', 'south')
            
        end

        % Button pushed function: Button_1
        function Button_1Pushed(app, event)
            % 初始窗口：显示标题
            app.HTML_Title.Visible = 'on';
            % 关闭坐标轴
            clf; cla; close();
            app.UIAxes.Visible = 'off';
            app.UIAxes.Position = [29 115 0 0];
            hide = [app.Gauge, app.GaugeLabel, app.Gauge_2, app.Gauge_2Label, app.TextArea, app.Image_weatherIcon, ...
                    app.Label, app.Label_2, app.Label_3, app.Label_4, app.Label_5, app.Label_6, app.Label_7];
            for h=hide
                h.Visible = 'off';
            end
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            selection = uiconfirm(app.UIFigure,['本软件可以获取某些城市当地的当天天气、未来七天天气和2021年历史天气查询。' ...
                '具体可以获取气温、湿度、天气状况、空气质量等信息'],'【关于——Made by Murphy】',...
                                    'Icon','info');
            %
        end

        % Callback function
        function Button_6Pushed(app, event)
            app.CityCode = app.DropDown.Value;
            app.City = app.M(app.CityCode);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML2 = uihtml(app.UIFigure);
            app.HTML2.HTMLSource = 'img.html';
            app.Button_1 = uibutton(app.UIFigure, 'push');
            app.Button_1.ButtonPushedFcn = createCallbackFcn(app, @Button_1Pushed, true);
            app.Button_1.Text = '初始';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '今日播报';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '未来天气';
            app.HTML3 = uihtml(app.UIFigure);
            app.HTML3.HTMLSource = 'border.html';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'circular');
            app.Gauge_2 = uigauge(app.UIFigure, 'circular');
            app.Gauge_2Label = uilabel(app.UIFigure);
            app.Gauge_2Label.Text = 'Gauge';
            app.HTML_clock = uihtml(app.UIFigure);
            app.HTML_clock.HTMLSource = 'clock.html';
            app.Image_weatherIcon = uiimage(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HTML_Title = uihtml(app.UIFigure);
            app.HTML_Title.HTMLSource = 'title.html';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '关于';
            app.DropDown = uidropdown(app.UIFigure);
            app.HTML_BorderSmall = uihtml(app.UIFigure);
            app.HTML_BorderSmall.HTMLSource = 'border_small.html';
            app.DropDown_Year = uidropdown(app.UIFigure);
            app.DropDown_Month = uidropdown(app.UIFigure);
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '历史天气';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end